classdef OceanOpticsSpectrometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        ConnectSpectrometerButton       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        IntegrationTimesEditField       matlab.ui.control.NumericEditField
        SelectSpectrometerDropDown      matlab.ui.control.DropDown
        SelectSpectrometerDropDownLabel  matlab.ui.control.Label
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        StartLiveViewButton             matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SetBGButton                     matlab.ui.control.Button
        NoBGButton                      matlab.ui.control.Button
        SetRefButton                    matlab.ui.control.Button
        NoRefButton                     matlab.ui.control.Button
        SaveSpectrumButton              matlab.ui.control.Button
        SaveRawSpectrumReferenceandBGButton  matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        PlotGrid                        matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        spectrometerObj             % Create MATLAB Instrument OmniDriver object
        spectrometerIndex
        channelIndex
        integrationTime
        timeObj
        rawSpectrum
        wavelength
        BGSpec
        RefSpec
        setRef
        isConnected
        init
    end
    
    methods (Access = private)
        
        function [wavelengthData, spectralData] = changeSpectrometer(app)
            wavelengthData = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            spectralData = invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex);
        end

        function LiveSpectrum(app, ~, ~)
            [wavelengthData, spectralData] = app.changeSpectrometer();
            app.rawSpectrum = spectralData;
            app.wavelength = wavelengthData;

            if app.init
                app.NoBGButtonPushed()
                app.NoRefButtonPushed()
                app.init = false;
            end
            bg = app.BGSpec;
            rf = app.RefSpec;

            if app.setRef
                plot(wavelengthData, (spectralData-bg)./(rf-bg), 'Parent', app.UIAxes);
            else
                plot(wavelengthData, (spectralData-bg)./10, 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeObj = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.1, ...                      % Period is 0.1 second
                'BusyMode', 'queue', ...                % Queue timer callbacks when busy
                'TimerFcn', @app.LiveSpectrum);         % Specify callback function
            
            app.spectrometerIndex = 0;      % first spectrometer by default
            app.channelIndex = 0;           % first channel by default
            app.BGSpec = 0;
            app.RefSpec = 1;
            app.setRef = false;
            app.isConnected = false;
            app.init = true;

        end

        % Button pushed function: ConnectSpectrometerButton
        function ConnectSpectrometerButtonPushed(app, event)
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            try
                connect(app.spectrometerObj)
                app.isConnected = true;
            catch
                app.Label.Text = 'No Devices Found';
                return
            end

            app.integrationTime = app.IntegrationTimesEditField.Value*1000000;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);   
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', app.spectrometerIndex, app.channelIndex, 1);
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', app.spectrometerIndex, app.channelIndex, 1);
            
            spectrometerName = invoke(app.spectrometerObj, 'getName', app.spectrometerIndex);
            
            % Get number of spectrometers connected
            numOfSpectrometers = invoke(app.spectrometerObj, 'getNumberOfSpectrometersFound');
            app.Label.Text = [num2str(numOfSpectrometers), ' ', spectrometerName, ' Connected'];

            for i = 1:numOfSpectrometers
                % Get spectrometer serial number
                spectrometerSerialNumber = invoke(app.spectrometerObj, 'getSerialNumber', i-1);
                app.SelectSpectrometerDropDown.Items(i) = cellstr(spectrometerSerialNumber);
            end
            
%             app.ConnectSpectrometerButton.Enable = 'off';

        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            app.integrationTime = app.IntegrationTimesEditField.Value*1000000;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);

        end

        % Value changed function: SelectSpectrometerDropDown
        function SelectSpectrometerDropDownValueChanged(app, event)
            app.spectrometerIndex = app.SelectSpectrometerDropDown.Value;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime);   
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', app.spectrometerIndex, app.channelIndex, 1);
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', app.spectrometerIndex, app.channelIndex, 1);

        end

        % Button pushed function: StartLiveViewButton
        function StartLiveViewButtonPushed(app, event)
            start(app.timeObj);
            app.StartLiveViewButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.SetBGButton.Enable = 'on';
            app.NoBGButton.Enable = 'on';
            app.SetRefButton.Enable = 'on';
            app.NoRefButton.Enable = 'on';
            app.SaveSpectrumButton.Enable = 'on';
            app.SaveRawSpectrumReferenceandBGButton.Enable = 'on';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.timeObj)
            app.StartLiveViewButton.Enable = 'on';
            app.StopButton.Enable = 'off';

        end

        % Button pushed function: SetBGButton
        function SetBGButtonPushed(app, event)
            app.BGSpec = app.rawSpectrum;

        end

        % Button pushed function: NoBGButton
        function NoBGButtonPushed(app, event)
            app.BGSpec = zeros(size(app.rawSpectrum));

        end

        % Button pushed function: SetRefButton
        function SetRefButtonPushed(app, event)
            app.RefSpec = app.rawSpectrum;   
            app.setRef = true;

        end

        % Button pushed function: NoRefButton
        function NoRefButtonPushed(app, event)
            app.RefSpec = ones(size(app.rawSpectrum));
            app.setRef = false;

        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            fmts = {'*.mat'; '*.csv'};
            [file, path, indx] = uiputfile(fmts);

            wavelengths = app.wavelength;
            if app.setRef
                spectrum = (app.rawSpectrum-app.BGSpec)./(app.RefSpec-app.BGSpec);
            else
                spectrum = (app.rawSpectrum-app.BGSpec);
            end
            
            if indx == 1
                save(fullfile(path, file), 'wavelengths', 'spectrum');
            elseif indx == 2
                matws=[wavelengths, spectrum];
                writematrix(matws, fullfile(path, file));
            end

            figure(app.UIFigure)

        end

        % Button pushed function: SaveRawSpectrumReferenceandBGButton
        function SaveRawSpectrumReferenceandBGButtonPushed(app, event)
            fmts = {'*.mat'; '*.csv'};
            [file, path, indx] = uiputfile(fmts);

            wavelengths = app.wavelength;
            spectrum = app.rawSpectrum;
            ref = app.RefSpec;
            bg = app.BGSpec;

            if indx == 1
                save(fullfile(path, file), 'wavelengths', 'spectrum', 'ref', 'bg');
            elseif indx == 2
                matws=[wavelengths, spectrum, ref, bg];
                writematrix(matws, fullfile(path, file));
            end

            figure(app.UIFigure)

        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isConnected
                listOfTimers = timerfindall;
                stop(listOfTimers(:))
                delete(listOfTimers(:))
                disconnect(app.spectrometerObj)
                delete(app.spectrometerObj)
            end
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {582, 582};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.ExitButton = uibutton(app.LeftPanelGrid, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.SaveRawSpectrumReferenceandBGButton = uibutton(app.LeftPanelGrid, 'push');
            app.SaveRawSpectrumReferenceandBGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRawSpectrumReferenceandBGButtonPushed, true);
            app.SaveRawSpectrumReferenceandBGButton.Enable = 'off';
            app.SaveRawSpectrumReferenceandBGButton.Text = {'Save Raw Spectrum,'; 'Reference, and BG'};
            app.SaveSpectrumButton = uibutton(app.LeftPanelGrid, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Enable = 'off';
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.NoRefButton = uibutton(app.LeftPanelGrid, 'push');
            app.NoRefButton.ButtonPushedFcn = createCallbackFcn(app, @NoRefButtonPushed, true);
            app.NoRefButton.Enable = 'off';
            app.NoRefButton.Tooltip = {'Remove reference value'};
            app.NoRefButton.Text = 'No Ref';
            app.SetRefButton = uibutton(app.LeftPanelGrid, 'push');
            app.SetRefButton.ButtonPushedFcn = createCallbackFcn(app, @SetRefButtonPushed, true);
            app.SetRefButton.Enable = 'off';
            app.SetRefButton.Tooltip = {'Set reference values to measure the relative intensity'};
            app.SetRefButton.Text = 'Set Ref';
            app.NoBGButton = uibutton(app.LeftPanelGrid, 'push');
            app.NoBGButton.ButtonPushedFcn = createCallbackFcn(app, @NoBGButtonPushed, true);
            app.NoBGButton.Enable = 'off';
            app.NoBGButton.Tooltip = {'Remove background value'};
            app.NoBGButton.Text = 'No BG';
            app.SetBGButton = uibutton(app.LeftPanelGrid, 'push');
            app.SetBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetBGButtonPushed, true);
            app.SetBGButton.Enable = 'off';
            app.SetBGButton.Tooltip = {'Subtract background and set current reading to zero'};
            app.SetBGButton.Text = 'Set BG';
            app.StopButton = uibutton(app.LeftPanelGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StartLiveViewButton = uibutton(app.LeftPanelGrid, 'push');
            app.StartLiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveViewButtonPushed, true);
            app.StartLiveViewButton.Text = 'Start Live View';
            app.IntegrationTimesEditFieldLabel = uilabel(app.LeftPanelGrid);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time (s)';
            app.SelectSpectrometerDropDownLabel = uilabel(app.LeftPanelGrid);
            app.SelectSpectrometerDropDownLabel.Text = 'Select Spectrometer';
            app.SelectSpectrometerDropDown = uidropdown(app.LeftPanelGrid);
            app.SelectSpectrometerDropDown.Items = {};
            app.SelectSpectrometerDropDown.ItemsData = [0 1];
            app.SelectSpectrometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSpectrometerDropDownValueChanged, true);
            app.SelectSpectrometerDropDown.Placeholder = 'N/A';
            app.SelectSpectrometerDropDown.Value = {};
            app.IntegrationTimesEditField = uieditfield(app.LeftPanelGrid, 'numeric');
            app.IntegrationTimesEditField.Limits = [0.008 3600];
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.IntegrationTimesEditField.Tooltip = {'Max: 3600s'; 'Min: 8ms'; 'Default: 1s'};
            app.IntegrationTimesEditField.Value = 1;
            app.Label = uilabel(app.LeftPanelGrid);
            app.Label.Text = '';
            app.ConnectSpectrometerButton = uibutton(app.LeftPanelGrid, 'push');
            app.ConnectSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometerButtonPushed, true);
            app.ConnectSpectrometerButton.Text = 'Connect Spectrometer';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotGrid = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.PlotGrid);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (counts)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanOpticsSpectrometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end